classdef DrawFigure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ControlPanelUIFigure            matlab.ui.Figure
        RightPanel                      matlab.ui.container.Panel
        YLabelTextArea                  matlab.ui.control.TextArea
        XLabelTextArea_2Label           matlab.ui.control.Label
        AxisBoxSwitch                   matlab.ui.control.ToggleSwitch
        AxisBoxSwitchLabel              matlab.ui.control.Label
        SaveFormatDropDown              matlab.ui.control.DropDown
        SaveFormatDropDownLabel         matlab.ui.control.Label
        SaveFiguresButton               matlab.ui.control.StateButton
        OthersLabel                     matlab.ui.control.Label
        ImportAllButton                 matlab.ui.control.Button
        SaveOptionsLabel                matlab.ui.control.Label
        XTickLabelTextArea              matlab.ui.control.TextArea
        XTickLabelTextAreaLabel         matlab.ui.control.Label
        FontSizeEditField               matlab.ui.control.NumericEditField
        FontSizeEditFieldLabel          matlab.ui.control.Label
        FontNameEditField               matlab.ui.control.EditField
        FontNameEditFieldLabel          matlab.ui.control.Label
        XLabelTextArea                  matlab.ui.control.TextArea
        xLabelTextAreaLabel             matlab.ui.control.Label
        ParametersSettingsLabel         matlab.ui.control.Label
        ResolutionEditField             matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel        matlab.ui.control.Label
        ExportAllButton                 matlab.ui.control.Button
        DrawButton                      matlab.ui.control.Button
        LeftPanel                       matlab.ui.container.Panel
        pValueSwitch                    matlab.ui.control.ToggleSwitch
        pValueSwitchLabel               matlab.ui.control.Label
        OutlierRemoveDropDown           matlab.ui.control.DropDown
        OutlierRemoveDropDownLabel      matlab.ui.control.Label
        SignificanceSwitch              matlab.ui.control.ToggleSwitch
        SignificanceSwitchLabel         matlab.ui.control.Label
        ErrorModeButtonGroup            matlab.ui.container.ButtonGroup
        stdButton                       matlab.ui.control.ToggleButton
        semButton                       matlab.ui.control.ToggleButton
        ErrorSwitch                     matlab.ui.control.ToggleSwitch
        ErrorSwitchLabel                matlab.ui.control.Label
        PartialLegendEditField          matlab.ui.control.EditField
        LegendSwitch                    matlab.ui.control.ToggleSwitch
        LegendSwitch_2Label             matlab.ui.control.Label
        LimitedDataSwitch               matlab.ui.control.ToggleSwitch
        LimitedDataSwitchLabel          matlab.ui.control.Label
        LimitedColumnEditField          matlab.ui.control.EditField
        LimitedColumnEditFieldLabel     matlab.ui.control.Label
        LimitedRowEditField             matlab.ui.control.EditField
        PartialAnalyzeEditField_2Label_2  matlab.ui.control.Label
        PartialAnalyzeEditField         matlab.ui.control.EditField
        PartialAnalyzeEditField_2Label  matlab.ui.control.Label
        LegendLabelsEditField           matlab.ui.control.EditField
        LoadDataButton                  matlab.ui.control.Button
        ShowPointsSwitch                matlab.ui.control.ToggleSwitch
        ShowPointsSwitch_2Label         matlab.ui.control.Label
        OverlaysSettingsLabel           matlab.ui.control.Label
        FigureTypeDropDown              matlab.ui.control.DropDown
        FigureTypeDropDownLabel         matlab.ui.control.Label
        DataSettingsLabel               matlab.ui.control.Label
    end

    properties (Access = public)
        %Use MVC pattern to draw the figures
        ModelObj; %Model object to hold the dataset and parameters
        ViewObj; %View object to display data
        ControllerObj; %Controller object to control all the callback
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ImportedModelObj)
            %Add all the subfolders to path
            pathToMLAPP = fileparts(mfilename('fullpath'));
            addpath(genpath(pathToMLAPP));

            %Create a default Model & initialize the UI components
            if(nargin == 1)
                app.ModelObj = Model();
                app.ModelObj.DataModel.ChangeData('LimitedRow', 0);
                app.ModelObj.DataModel.ChangeData('LimitedColumn', 0);
            elseif nargin == 2
                app.ModelObj = ImportedModelObj;  
            end

            ParametersModel = app.ModelObj.ParametersModel;
            DataModel = app.ModelObj.DataModel;            
            OverlaysModel = app.ModelObj.OverlaysModel;
            NonCallbackModel = app.ModelObj.NonCallbackModel;
            
             %Create a controller object
                app.ControllerObj = Controller(app);

            app.FigureTypeDropDown.Value = app.ModelObj.DataModel.FigureType;
            app.XLabelTextArea.Value = ParametersModel.XLabel;
            app.XTickLabelTextArea.Value = ParametersModel.XTickLabel;%strcat( ...
            %cellfun(@string, ParametersModel.XTickLabel));
            app.FontNameEditField.Value = ParametersModel.FontName;
            app.FontSizeEditField.Value = ParametersModel.FontSize;
            app.AxisBoxSwitch.Value = ParametersModel.ShowAxisBox;

            app.ResolutionEditField.Value = NonCallbackModel.Resolution;
            app.SaveFormatDropDown.Value = NonCallbackModel.SaveFormat;

            app.PartialAnalyzeEditField.Value = num2str(DataModel.PartialAnalyze);
            app.LimitedRowEditField.Value = num2str(DataModel.LimitedRow);
            app.LimitedColumnEditField.Value = num2str(DataModel.LimitedColumn);
            app.ShowPointsSwitch.Value = OverlaysModel.ShowPoints;
            app.ErrorSwitch.Value = OverlaysModel.ShowError;
            app.LimitedDataSwitch.Value = DataModel.LimitedData;
            app.LegendSwitch.Value = OverlaysModel.ShowLegend;
            app.LegendLabelsEditField.Value = OverlaysModel.Legend;
            app.PartialLegendEditField.Value = string(OverlaysModel.PartialLegend);
            
           
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonCallbackFun(app, event)
            event.Source.Text = "Busy";
            event.Source.Enable = "off";
           
            pathToMLAPP = fileparts(mfilename('fullpath'));
            cd(pathToMLAPP)
            [file,path] = uigetfile('*.mat', ...
                'Select an .mat data', '.\Examples\ExampleRawDataset.mat');   
            %Message when loading is cancelled
            if isequal(file,0)
                disp('User selected Cancel')
                event.Source.Text = "Load Data";
            else
                RawDataset = load(fullfile(path,file), 'RawDataset').RawDataset;
                app.ControllerObj.DataChangedCallback('RawDataset', RawDataset);
                event.Source.Text = "Loaded";
            end            
            
            event.Source.Enable = "on";   
        end

        % Button pushed function: DrawButton
        function DrawButtonCallbackFun(app, event)
            if(~isempty(app.ViewObj))
                delete(app.ViewObj.hFig)
                clear View
            end
            app.SaveFiguresButton.Enable = 'on';
            app.ViewObj = View.GetView(app);
        end

        % Value changed function: FigureTypeDropDown
        function FigureTypeDropDownValueChanged(app, event)
            value = app.FigureTypeDropDown.Value;
            app.ControllerObj.DataChangedCallback('FigureType', value);
        end

        % Value changed function: XLabelTextArea
        function XLabelTextAreaValueChanged(app, event)
            value = app.XLabelTextArea.Value;
            app.ControllerObj.ParametersChangedCallback('XLabel', value);
        end

        % Value changed function: XTickLabelTextArea
        function XTickLabelTextAreaValueChanged(app, event)
            value = app.XTickLabelTextArea.Value;
            app.ControllerObj.ParametersChangedCallback('XTickLabel', value);
        end

        % Value changed function: FontNameEditField
        function FontNameEditFieldValueChanged(app, event)
            value = app.FontNameEditField.Value;
            app.ControllerObj.ParametersChangedCallback('FontName', value);
        end

        % Value changed function: FontSizeEditField
        function FontSizeEditFieldValueChanged(app, event)
            value = app.FontSizeEditField.Value;
            app.ControllerObj.ParametersChangedCallback('FontSize', value);
        end

        % Value changed function: PartialAnalyzeEditField
        function PartialAnalyzeEditFieldValueChanged(app, event)
            value = app.PartialAnalyzeEditField.Value;
            app.ControllerObj.DataChangedCallback('PartialAnalyze', str2num(value));
        end

        % Value changed function: ShowPointsSwitch
        function ShowPointsSwitchValueChanged(app, event)
            value = app.ShowPointsSwitch.Value;
            app.ControllerObj.OverlaysChangedCallback('ShowPoints', value);
        end

        % Value changed function: LimitedRowEditField
        function LimitedRowEditFieldValueChanged(app, event)
            value = app.LimitedRowEditField.Value;
            app.ControllerObj.DataChangedCallback('LimitedRow', str2num(value));
        end

        % Value changed function: LimitedColumnEditField
        function LimitedColumnEditFieldValueChanged(app, event)
            value = app.LimitedColumnEditField.Value;
            app.ControllerObj.DataChangedCallback('LimitedColumn', str2num(value));
        end

        % Value changed function: LimitedDataSwitch
        function LimitedDataSwitchValueChanged(app, event)
            value = app.LimitedDataSwitch.Value;
            if(value)
                app.LimitedRowEditField.Enable = "on";
                app.LimitedColumnEditField.Enable = "on";
            else
                app.LimitedRowEditField.Enable = "off";
                app.LimitedColumnEditField.Enable = "off";
            end
            app.ControllerObj.DataChangedCallback('LimitedData', value);
        end

        % Value changed function: LegendSwitch
        function LegendSwitchValueChanged(app, event)
            value = app.LegendSwitch.Value;
            if(value)
                app.LegendLabelsEditField.Enable = "on";
                app.PartialLegendEditField.Enable = "on";
            else
                app.LegendLabelsEditField.Enable = "off";
                app.PartialLegendEditField.Enable = "off";
            end
            app.ControllerObj.OverlaysChangedCallback('ShowLegend', value);
        end

        % Value changed function: LegendLabelsEditField
        function LegendLabelsEditFieldValueChanged(app, event)
            value = app.LegendLabelsEditField.Value;
            app.ControllerObj.OverlaysChangedCallback('Legend', value);
        end

        % Value changed function: PartialLegendEditField
        function PartialLegendEditFieldValueChanged(app, event)
            value = app.PartialLegendEditField.Value;
            app.ControllerObj.OverlaysChangedCallback('PartialLegend', str2num(value));
        end

        % Button pushed function: ExportAllButton
        function ExportAllButtonPushed(app, event)
            event.Source.Text = "Busy";
            event.Source.Enable = "off";
            pathToMLAPP = fileparts(mfilename('fullpath'));
            cd(pathToMLAPP)
            ExportedModelObj = app.ModelObj;
            save('Exported\ExportedModelObj.mat', 'ExportedModelObj');
            event.Source.Text = "Exported";
            event.Source.Enable = "on";            
        end

        % Button pushed function: ImportAllButton
        function ImportAllButtonPushed(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            cd(pathToMLAPP)

            [file,path] = uigetfile('*.mat', ...
                'Select an .mat data', 'Exported\ExportedModelObj.mat');
            if isequal(file,0)%Message when loading is cancelled
                disp('User selected Cancel')
            else
                ImportedModelObj = load(fullfile(path,file), 'ExportedModelObj').ExportedModelObj;
                app.startupFcn(ImportedModelObj);
            end
        end

        % Value changed function: SaveFiguresButton
        function SaveFiguresButtonValueChanged(app, event)
            event.Source.Text = "Busy";
            event.Source.Enable = "off";
            
            pathToMLAPP = fileparts(mfilename('fullpath'));
            cd(pathToMLAPP)
            app.ModelObj.NonCallbackModel.SaveFigures();

            event.Source.Text = "Saved";
            event.Source.Enable = "on";   
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            value = app.ResolutionEditField.Value;
            app.ModelObj.NonCallbackModel.Resolution = value;
        end

        % Value changed function: SaveFormatDropDown
        function SaveFormatDropDownValueChanged(app, event)
            value = app.SaveFormatDropDown.Value;
            app.ModelObj.NonCallbackModel.SaveFormat = value;
        end

        % Value changed function: AxisBoxSwitch
        function AxisBoxSwitchValueChanged(app, event)
            value = app.AxisBoxSwitch.Value;
            app.ControllerObj.ParametersChangedCallback('ShowAxisBox', value);
        end

        % Close request function: ControlPanelUIFigure
        function ControlPanelUIFigureCloseRequest(app, event)
            delete(app)
            % TheViewObj = app.ViewObj;
            CleanView = onCleanup(@() clear('View'));
        end

        % Value changed function: ErrorSwitch
        function ErrorSwitchValueChanged(app, event)
            value = app.ErrorSwitch.Value;
            if(value)    
                if(strcmp(app.ModelObj.DataModel.ErrorMode, 'sem'))
                    app.semButton.Value = true;
                elseif(strcmp(app.ModelObj.DataModel.ErrorMode, 'std'))
                    app.stdButton.Value = true;
                end
                app.ErrorModeButtonGroup.Visible = 'on';
            else
                app.ErrorModeButtonGroup.Visible = 'off';
            end
            app.ControllerObj.OverlaysChangedCallback('ShowError', value);
        end

        % Selection changed function: ErrorModeButtonGroup
        function ErrorModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ErrorModeButtonGroup.SelectedObject;
            app.ControllerObj.DataChangedCallback('ErrorMode', selectedButton.Text);
        end

        % Value changed function: SignificanceSwitch
        function SignificanceSwitchValueChanged(app, event)
            value = app.SignificanceSwitch.Value;
            if(value)
                app.OutlierRemoveDropDown.Visible = 'on';
                app.pValueSwitch.Value = app.ModelObj.OverlaysModel.ShowpValue;
                app.pValueSwitch.Visible = 'on';
            else
                app.OutlierRemoveDropDown.Visible = 'off';
                app.pValueSwitch.Visible = 'off';
            end
            app.ControllerObj.OverlaysChangedCallback('ShowSignificance', value);
        end

        % Value changed function: OutlierRemoveDropDown
        function OutlierRemoveDropDownValueChanged(app, event)
            value = app.OutlierRemoveDropDown.Value;
            app.ControllerObj.OverlaysChangedCallback('OutlierRemove', value);
        end

        % Value changed function: pValueSwitch
        function pValueSwitchValueChanged(app, event)
            value = app.pValueSwitch.Value;
            app.ControllerObj.OverlaysChangedCallback('ShowpValue', value);
        end

        % Value changed function: YLabelTextArea
        function YLabelTextAreaValueChanged(app, event)
            value = app.YLabelTextArea.Value;
            app.ControllerObj.ParametersChangedCallback('YLabel', value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ControlPanelUIFigure = uifigure('Visible', 'off');
            app.ControlPanelUIFigure.Name = 'Control Panel';
            app.ControlPanelUIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'Settings.jpg');
            app.ControlPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @ControlPanelUIFigureCloseRequest, true);
            app.LeftPanel = uipanel(app.ControlPanelUIFigure);
            app.DataSettingsLabel = uilabel(app.LeftPanel);
            app.DataSettingsLabel.Text = 'Data Settings';
            app.FigureTypeDropDownLabel = uilabel(app.LeftPanel);
            app.FigureTypeDropDownLabel.Text = 'Figure Type';
            app.FigureTypeDropDown = uidropdown(app.LeftPanel);
            app.FigureTypeDropDown.Items = {'Bar', 'Line', 'Violin', 'Box'};
            app.FigureTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FigureTypeDropDownValueChanged, true);
            app.FigureTypeDropDown.Value = 'Bar';
            app.OverlaysSettingsLabel = uilabel(app.LeftPanel);
            app.OverlaysSettingsLabel.Text = 'Overlays Settings';
            app.ShowPointsSwitch_2Label = uilabel(app.LeftPanel);
            app.ShowPointsSwitch_2Label.Text = 'ShowPoints';
            app.ShowPointsSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.ShowPointsSwitch.ItemsData = [0 1];
            app.ShowPointsSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowPointsSwitchValueChanged, true);
            app.ShowPointsSwitch.Value = 0;
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonCallbackFun, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LegendLabelsEditField = uieditfield(app.LeftPanel, 'text');
            app.LegendLabelsEditField.ValueChangedFcn = createCallbackFcn(app, @LegendLabelsEditFieldValueChanged, true);
            app.LegendLabelsEditField.Enable = 'off';
            app.LegendLabelsEditField.Tooltip = {'The names of legend, use , to separate'};
            app.PartialAnalyzeEditField_2Label = uilabel(app.LeftPanel);
            app.PartialAnalyzeEditField_2Label.Text = 'PartialAnalyze';
            app.PartialAnalyzeEditField = uieditfield(app.LeftPanel, 'text');
            app.PartialAnalyzeEditField.ValueChangedFcn = createCallbackFcn(app, @PartialAnalyzeEditFieldValueChanged, true);
            app.PartialAnalyzeEditField.Tooltip = {'Input 0 to analyze all the variables'};
            app.PartialAnalyzeEditField_2Label_2 = uilabel(app.LeftPanel);
            app.PartialAnalyzeEditField_2Label_2.Enable = 'off';
            app.PartialAnalyzeEditField_2Label_2.Text = 'LimitedRow';
            app.LimitedRowEditField = uieditfield(app.LeftPanel, 'text');
            app.LimitedRowEditField.ValueChangedFcn = createCallbackFcn(app, @LimitedRowEditFieldValueChanged, true);
            app.LimitedRowEditField.Enable = 'off';
            app.LimitedRowEditField.Tooltip = {'Input 0 to analyze all the rows in the data'};
            app.LimitedColumnEditFieldLabel = uilabel(app.LeftPanel);
            app.LimitedColumnEditFieldLabel.Enable = 'off';
            app.LimitedColumnEditFieldLabel.Text = 'LimitedColumn';
            app.LimitedColumnEditField = uieditfield(app.LeftPanel, 'text');
            app.LimitedColumnEditField.ValueChangedFcn = createCallbackFcn(app, @LimitedColumnEditFieldValueChanged, true);
            app.LimitedColumnEditField.Enable = 'off';
            app.LimitedColumnEditField.Tooltip = {'Input 0 to analyze all the columns in the data'};
            app.LimitedDataSwitchLabel = uilabel(app.LeftPanel);
            app.LimitedDataSwitchLabel.Text = 'LimitedData';
            app.LimitedDataSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.LimitedDataSwitch.ItemsData = [0 1];
            app.LimitedDataSwitch.ValueChangedFcn = createCallbackFcn(app, @LimitedDataSwitchValueChanged, true);
            app.LimitedDataSwitch.Value = 0;
            app.LegendSwitch_2Label = uilabel(app.LeftPanel);
            app.LegendSwitch_2Label.Text = 'Legend';
            app.LegendSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.LegendSwitch.ItemsData = [0 1];
            app.LegendSwitch.ValueChangedFcn = createCallbackFcn(app, @LegendSwitchValueChanged, true);
            app.LegendSwitch.Value = 0;
            app.PartialLegendEditField = uieditfield(app.LeftPanel, 'text');
            app.PartialLegendEditField.ValueChangedFcn = createCallbackFcn(app, @PartialLegendEditFieldValueChanged, true);
            app.PartialLegendEditField.Enable = 'off';
            app.PartialLegendEditField.Tooltip = {'Legend on which figure (use , to separate)'};
            app.ErrorSwitchLabel = uilabel(app.LeftPanel);
            app.ErrorSwitchLabel.Text = 'Error';
            app.ErrorSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.ErrorSwitch.ItemsData = [0 1];
            app.ErrorSwitch.ValueChangedFcn = createCallbackFcn(app, @ErrorSwitchValueChanged, true);
            app.ErrorSwitch.Value = 0;
            app.ErrorModeButtonGroup = uibuttongroup(app.LeftPanel);
            app.ErrorModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ErrorModeButtonGroupSelectionChanged, true);
            app.ErrorModeButtonGroup.Title = 'ErrorMode';
            app.ErrorModeButtonGroup.Visible = 'off';
            app.semButton = uitogglebutton(app.ErrorModeButtonGroup);
            app.semButton.Text = 'sem';
            app.semButton.Value = true;
            app.stdButton = uitogglebutton(app.ErrorModeButtonGroup);
            app.stdButton.Text = 'std';
            app.SignificanceSwitchLabel = uilabel(app.LeftPanel);
            app.SignificanceSwitchLabel.Text = 'Significance';
            app.SignificanceSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.SignificanceSwitch.ItemsData = [0 1];
            app.SignificanceSwitch.ValueChangedFcn = createCallbackFcn(app, @SignificanceSwitchValueChanged, true);
            app.SignificanceSwitch.Value = 0;
            app.OutlierRemoveDropDownLabel = uilabel(app.LeftPanel);
            app.OutlierRemoveDropDownLabel.Enable = 'off';
            app.OutlierRemoveDropDownLabel.Text = 'OutlierRemove';
            app.OutlierRemoveDropDown = uidropdown(app.LeftPanel);
            app.OutlierRemoveDropDown.Items = {'none', 'quartiles', 'median'};
            app.OutlierRemoveDropDown.ValueChangedFcn = createCallbackFcn(app, @OutlierRemoveDropDownValueChanged, true);
            app.OutlierRemoveDropDown.Visible = 'off';
            app.OutlierRemoveDropDown.Tooltip = {'Remove outliers or not when calculating significance, offers ''quartiles'' and ''mean'' method, see Matlab documents for detail'};
            app.OutlierRemoveDropDown.Value = 'none';
            app.pValueSwitchLabel = uilabel(app.LeftPanel);
            app.pValueSwitchLabel.Text = 'pValue';
            app.pValueSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.pValueSwitch.ItemsData = [0 1];
            app.pValueSwitch.ValueChangedFcn = createCallbackFcn(app, @pValueSwitchValueChanged, true);
            app.pValueSwitch.Visible = 'off';
            app.pValueSwitch.Value = 0;
            app.RightPanel = uipanel(app.ControlPanelUIFigure);
            app.DrawButton = uibutton(app.RightPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonCallbackFun, true);
            app.DrawButton.Text = 'Draw';
            app.ExportAllButton = uibutton(app.RightPanel, 'push');
            app.ExportAllButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAllButtonPushed, true);
            app.ExportAllButton.Tooltip = {'Export raw dataset & all the settings into the folder .\Exported'};
            app.ExportAllButton.Text = 'ExportAll';
            app.ResolutionEditFieldLabel = uilabel(app.RightPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.RightPanel, 'numeric');
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.ResolutionEditField.Value = 1200;
            app.ParametersSettingsLabel = uilabel(app.RightPanel);
            app.ParametersSettingsLabel.Text = 'Parameters Settings';
            app.xLabelTextAreaLabel = uilabel(app.RightPanel);
            app.xLabelTextAreaLabel.Text = 'XLabel';
            app.XLabelTextArea = uitextarea(app.RightPanel);
            app.XLabelTextArea.ValueChangedFcn = createCallbackFcn(app, @XLabelTextAreaValueChanged, true);
            app.FontNameEditFieldLabel = uilabel(app.RightPanel);
            app.FontNameEditFieldLabel.Text = 'FontName';
            app.FontNameEditField = uieditfield(app.RightPanel, 'text');
            app.FontNameEditField.ValueChangedFcn = createCallbackFcn(app, @FontNameEditFieldValueChanged, true);
            app.FontSizeEditFieldLabel = uilabel(app.RightPanel);
            app.FontSizeEditFieldLabel.Text = 'FontSize';
            app.FontSizeEditField = uieditfield(app.RightPanel, 'numeric');
            app.FontSizeEditField.ValueChangedFcn = createCallbackFcn(app, @FontSizeEditFieldValueChanged, true);
            app.FontSizeEditField.Value = 10;
            app.XTickLabelTextAreaLabel = uilabel(app.RightPanel);
            app.XTickLabelTextAreaLabel.Text = 'XTickLabel';
            app.XTickLabelTextArea = uitextarea(app.RightPanel);
            app.XTickLabelTextArea.ValueChangedFcn = createCallbackFcn(app, @XTickLabelTextAreaValueChanged, true);
            app.SaveOptionsLabel = uilabel(app.RightPanel);
            app.SaveOptionsLabel.Text = 'Save Options';
            app.ImportAllButton = uibutton(app.RightPanel, 'push');
            app.ImportAllButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAllButtonPushed, true);
            app.ImportAllButton.Text = 'ImportAll';
            app.OthersLabel = uilabel(app.RightPanel);
            app.OthersLabel.Text = 'Others';
            app.SaveFiguresButton = uibutton(app.RightPanel, 'state');
            app.SaveFiguresButton.ValueChangedFcn = createCallbackFcn(app, @SaveFiguresButtonValueChanged, true);
            app.SaveFiguresButton.Enable = 'off';
            app.SaveFiguresButton.Tooltip = {'Save the figures in designated resolution and format'};
            app.SaveFiguresButton.Text = 'Save Figures';
            app.SaveFormatDropDownLabel = uilabel(app.RightPanel);
            app.SaveFormatDropDownLabel.Text = 'SaveFormat';
            app.SaveFormatDropDown = uidropdown(app.RightPanel);
            app.SaveFormatDropDown.Items = {'tif', 'eps', 'pdf'};
            app.SaveFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @SaveFormatDropDownValueChanged, true);
            app.SaveFormatDropDown.Value = 'tif';
            app.AxisBoxSwitchLabel = uilabel(app.RightPanel);
            app.AxisBoxSwitchLabel.Text = 'AxisBox';
            app.AxisBoxSwitch = uiswitch(app.RightPanel, 'toggle');
            app.AxisBoxSwitch.ItemsData = [0 1];
            app.AxisBoxSwitch.ValueChangedFcn = createCallbackFcn(app, @AxisBoxSwitchValueChanged, true);
            app.AxisBoxSwitch.Value = 0;
            app.XLabelTextArea_2Label = uilabel(app.RightPanel);
            app.XLabelTextArea_2Label.Text = 'YLabel';
            app.YLabelTextArea = uitextarea(app.RightPanel);
            app.YLabelTextArea.ValueChangedFcn = createCallbackFcn(app, @YLabelTextAreaValueChanged, true);
            app.YLabelTextArea.Tooltip = {'The label of y axis, use , to separate'};
            % Show the figure after all components are created
            app.ControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DrawFigure(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ControlPanelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ControlPanelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ControlPanelUIFigure)
        end
    end
end